classdef mytest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TextArea_2    matlab.ui.control.TextArea
        Label_3       matlab.ui.control.Label
        TabGroup2     matlab.ui.container.TabGroup
        Tab           matlab.ui.container.Tab
        UIAxes_Back   matlab.ui.control.UIAxes
        UIAxes_Front  matlab.ui.control.UIAxes
        TabGroup      matlab.ui.container.TabGroup
        Tab_2         matlab.ui.container.Tab
        UIAxes_Posi   matlab.ui.control.UIAxes
        UIAxes_GLR    matlab.ui.control.UIAxes
        UIAxes_Xcor   matlab.ui.control.UIAxes
        UIAxes_ED     matlab.ui.control.UIAxes
        choose        matlab.ui.control.DropDown
        Label_2       matlab.ui.control.Label
        Button        matlab.ui.control.StateButton
        ButtonGroup   matlab.ui.container.ButtonGroup
        Button_2      matlab.ui.control.RadioButton
        StopButton    matlab.ui.control.RadioButton
        StartButton   matlab.ui.control.RadioButton
        TextArea      matlab.ui.control.TextArea
        Label         matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text            
                case '开始'
                      app.UIAxes.Visible = 'off';
                      switch app.choose.Value
                          
                          case  '回放'
                                 scDynamicTxV3_2_1_function(app.UIAxes,app.UIAxes_ED,app.UIAxes_Xcor,app.UIAxes_GLR,app.UIAxes_Posi,app.TextArea,app.Button);
                          case  '仿真'
                                 scDynamicTx(app.UIAxes,app.UIAxes_ED,app.UIAxes_Xcor,app.UIAxes_GLR,app.UIAxes_Posi,app.TextArea,app.TextArea_2,app.Button,app.UIAxes_Front,app.UIAxes_Back);  
                          case  '未选'
                                 pause(0.01)
                      end

                case '结束'
                      delete(app)
                   
                case  '未选择'
                      pause(0.01)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '地图')
            xlabel(app.UIAxes, '经度')
            ylabel(app.UIAxes, '纬度')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '仿真模拟日志';
            app.TextArea = uitextarea(app.UIFigure);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '模拟控制开关';
            app.StartButton = uiradiobutton(app.ButtonGroup);
            app.StartButton.Text = '开始';
            app.StopButton = uiradiobutton(app.ButtonGroup);
            app.StopButton.Text = '结束';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '未选择';
            app.Button_2.Value = true;
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.Text = '暂停/继续';
            app.Button.Value = true;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '选项';
            app.choose = uidropdown(app.UIFigure);
            app.choose.Items = {'回放', '仿真', '未选'};
            app.choose.Value = '未选';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '实时检测定位结果';
            app.UIAxes_ED = uiaxes(app.Tab_2);
            title(app.UIAxes_ED, '单节点能量检测')
            xlabel(app.UIAxes_ED, 'countT')
            ylabel(app.UIAxes_ED, '检验统计量')
            zlabel(app.UIAxes_ED, 'Z')
            app.UIAxes_Xcor = uiaxes(app.Tab_2);
            title(app.UIAxes_Xcor, '互相关检测')
            xlabel(app.UIAxes_Xcor, 'countT')
            ylabel(app.UIAxes_Xcor, '检验统计量')
            zlabel(app.UIAxes_Xcor, 'Z')
            app.UIAxes_GLR = uiaxes(app.Tab_2);
            title(app.UIAxes_GLR, '广义似然比检测')
            xlabel(app.UIAxes_GLR, 'countT')
            ylabel(app.UIAxes_GLR, '检验统计量')
            zlabel(app.UIAxes_GLR, 'Z')
            app.UIAxes_Posi = uiaxes(app.Tab_2);
            title(app.UIAxes_Posi, '两步法时差定位')
            xlabel(app.UIAxes_Posi, 'countT')
            ylabel(app.UIAxes_Posi, '定位误差/m')
            zlabel(app.UIAxes_Posi, 'Z')
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = '时频图';
            app.UIAxes_Front = uiaxes(app.Tab);
            title(app.UIAxes_Front, '前节点时频图')
            xlabel(app.UIAxes_Front, 't/s')
            ylabel(app.UIAxes_Front, '频率/Hz')
            zlabel(app.UIAxes_Front, 'PSD (dB/Hz)')
            app.UIAxes_Back = uiaxes(app.Tab);
            title(app.UIAxes_Back, '后节点时频图')
            xlabel(app.UIAxes_Back, 't/s')
            ylabel(app.UIAxes_Back, '频率/Hz')
            zlabel(app.UIAxes_Back, 'PSD (dB/Hz)')
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '检测方法 选择结果';
            app.TextArea_2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mytest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end