classdef mytestV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        startButton                     matlab.ui.container.toolbar.PushTool
        PushPauseButton                 matlab.ui.container.toolbar.PushTool
        PushRestartButoon               matlab.ui.container.toolbar.PushTool
        stopButton                      matlab.ui.container.toolbar.PushTool
        GLR_DPD_PHD_Button1             matlab.ui.control.StateButton
        GLR_DPD_Interp_Button1          matlab.ui.control.StateButton
        GLR_DPD_Button1                 matlab.ui.control.StateButton
        TDOA_Button1                    matlab.ui.control.StateButton
        TabGroup3                       matlab.ui.container.TabGroup
        Tab_10                          matlab.ui.container.Tab
        TextArea_TX0                    matlab.ui.control.TextArea
        TX1Log                          matlab.ui.container.Tab
        TextArea_TX1                    matlab.ui.control.TextArea
        TX2Log                          matlab.ui.container.Tab
        TextArea_TX2                    matlab.ui.control.TextArea
        TX3Log                          matlab.ui.container.Tab
        TextArea_TX3                    matlab.ui.control.TextArea
        TX4Log                          matlab.ui.container.Tab
        TextArea_TX4                    matlab.ui.control.TextArea
        TabGroup2                       matlab.ui.container.TabGroup
        RxTab                           matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        GLR_DPD_PHD_Button              matlab.ui.control.CheckBox
        GLR_DPD_Interp_Button           matlab.ui.control.CheckBox
        GLR_DPD_Button                  matlab.ui.control.CheckBox
        TDOA_Button                     matlab.ui.control.CheckBox
        RxPanel_Basic_System_Parameters_2  matlab.ui.container.Panel
        RxDropDown                      matlab.ui.control.DropDown
        RxDropDownLabel                 matlab.ui.control.Label
        input_sampRate                  matlab.ui.control.DropDown
        Label_15                        matlab.ui.control.Label
        input_obsTime                   matlab.ui.control.DropDown
        Label_13                        matlab.ui.control.Label
        Tab_9                           matlab.ui.container.Tab
        input_pf                        matlab.ui.control.NumericEditField
        Label_18                        matlab.ui.control.Label
        input_MC                        matlab.ui.control.NumericEditField
        Label_17                        matlab.ui.control.Label
        threshold_file_path             matlab.ui.control.EditField
        Label_16                        matlab.ui.control.Label
        force_monte_carlo               matlab.ui.control.CheckBox
        input_threshold_from_localfile  matlab.ui.control.Button
        auto_set_threshold              matlab.ui.control.Button
        set_threshold_file_path         matlab.ui.control.Button
        TxPanel                         matlab.ui.container.Panel
        TxPanel_Basic_System_Parameters  matlab.ui.container.Panel
        Reset                           matlab.ui.control.Button
        SetParaButton                   matlab.ui.control.Button
        input_bandwidth                 matlab.ui.control.DropDown
        Label_12                        matlab.ui.control.Label
        input_transmit_Power            matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        input_mod_type                  matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        TxDropDown                      matlab.ui.control.DropDown
        TxDropDownLabel                 matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        TX1detcAndLoca                  matlab.ui.container.Tab
        Loca_TX1                        matlab.ui.container.Panel
        UIAxes_Posi_TX1                 matlab.ui.control.UIAxes
        Detection_TX1                   matlab.ui.container.Panel
        GLRTS_TX1                       matlab.ui.control.LinearGauge
        GLR                             matlab.ui.control.Label
        XcorrTS_TX1                     matlab.ui.control.LinearGauge
        Xcorr                           matlab.ui.control.Label
        SingleNodeED                    matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Method                          matlab.ui.control.Label
        SingleNodeEDTS_TX1              matlab.ui.control.LinearGauge
        TX2detcAndLoca                  matlab.ui.container.Tab
        Loca_TX2                        matlab.ui.container.Panel
        UIAxes_Posi_TX2                 matlab.ui.control.UIAxes
        Detection_TX2                   matlab.ui.container.Panel
        GLRTS_TX2                       matlab.ui.control.LinearGauge
        GLR_2                           matlab.ui.control.Label
        XcorrTS_TX2                     matlab.ui.control.LinearGauge
        Xcorr_2                         matlab.ui.control.Label
        SingleNodeED_2                  matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Method_2                        matlab.ui.control.Label
        SingleNodeEDTS_TX2              matlab.ui.control.LinearGauge
        TX3detcAndLoca                  matlab.ui.container.Tab
        Loca_TX3                        matlab.ui.container.Panel
        UIAxes_Posi_TX3                 matlab.ui.control.UIAxes
        Detection_TX3                   matlab.ui.container.Panel
        GLRTS_TX3                       matlab.ui.control.LinearGauge
        GLR_3                           matlab.ui.control.Label
        XcorrTS_TX3                     matlab.ui.control.LinearGauge
        Xcorr_3                         matlab.ui.control.Label
        SingleNodeED_3                  matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        Method_3                        matlab.ui.control.Label
        SingleNodeEDTS_TX3              matlab.ui.control.LinearGauge
        TX4TabdetcAndLoca               matlab.ui.container.Tab
        Loca_TX4                        matlab.ui.container.Panel
        UIAxes_Posi_TX4                 matlab.ui.control.UIAxes
        Detection_TX4                   matlab.ui.container.Panel
        GLRTS_TX4                       matlab.ui.control.LinearGauge
        GLR_4                           matlab.ui.control.Label
        XcorrTS_TX4                     matlab.ui.control.LinearGauge
        Xcorr_4                         matlab.ui.control.Label
        SingleNodeED_4                  matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Method_4                        matlab.ui.control.Label
        SingleNodeEDTS_TX4              matlab.ui.control.LinearGauge
        UIAxes                          matlab.ui.control.UIAxes
    end

  properties (Access = public)
   path_enemy = {[29.94897,122.64,1000;
       29.915698, 122.539497,1000;
       29.891439,122.507425, 1000;
       29.87896, 122.507981,1000;
       29.845698, 122.569497,1000;
      29.825458, 122.636762,1000];
      [30.080,122.45,1000;
      29.98,122.40,1000;
      29.96,122.55,1000];
      [30.06,122.59,1000;
      29.96, 122.57,1000;
      29.90,122.662,1000];
      [29.803,122.45,1000;
      29.84,122.61,1000;
      29.8152,122.63,1000;
      29.805,122.65,1000]

      }
      

   drone_positions =[
    29.860016,122.393303,1000;
    29.90309,122.401622,1000;
    29.93189,122.397188,1000;
    29.947428,122.359096,1000;
    29.89047,122.465459,1000;
    29.92461,122.464597,1000
];

       
      CAR_F = 1.42e9 %Hz
      Bandwidth = 20000;  %Hz
      moduType  = "BPSK"; 
      transmitPower = 0.02;%w

      TxNodeNum = 4;
      TxNodeNumIndex;
      speed_enemy = [300,300,300,300];
      num_step = 180;


      RecNodeNum = 6;


      source_para  struct;
      RecNode_para struct;
      sensitivity = -90;

      log_TX0 = {};
      log_TX1 = {};
      log_TX2 = {};
      log_TX3 = {};
      log_TX4 = {};

      DetcResult(1,1) struct
      Flag = 1; 
  end
    
  methods (Access = public)

  
        function pushLogMsg(app, msg, num)


            % update messages
            app.(['log_TX' num2str(num)]) = [app.(['log_TX' num2str(num)]),{msg}];
            app.(['TextArea_TX' num2str(num)']).Value = app.(['log_TX' num2str(num)]) ;
            % scroll to bottom
            app.(['TextArea_TX' num2str(num)']).scroll("bottom");
            
        end

        function  DetecResult = findThresholdsByMonteCarlo(app)
            SENS = app.sensitivity ;
            PF = app.input_pf.Value;
            LEN_SIG = str2double(app.input_obsTime.Value)*str2double(app.input_sampRate.Value);
            NFFT = 1024;
            nNode = app.RecNodeNum;
            Monte_Carlo = app.input_MC.Value ;
            app.DetcResult.Th.ED = calDetcTh(SENS, PF, LEN_SIG, 'NumFFT', NFFT, 'method', 'ED', 'CalType', 'AVE','MonteCarlo',Monte_Carlo);
            app.DetcResult.MulTh.Xcor = calTimeCorrTh(SENS, PF,LEN_SIG,'MonteCarlo',Monte_Carlo);
            app.DetcResult.MulTh.GLR = calMulDetch(SENS,PF,nNode,LEN_SIG,'MonteCarlo',Monte_Carlo);
            DetecResult = app.DetcResult;
        end


  end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            pushLogMsg(app, '正在初始化应用...',0);
            app.UIAxes.Visible = 'off';
            % load thresholds
            tmp = load(fullfile('data', ['DetcResult', num2str(round(str2double(app.input_sampRate.Value)/1e3)) 'K.mat']));
            app.DetcResult = tmp.DetcResult;
            % load map
            load(['map30Km.mat']);
            geoshow(app.UIAxes,A, R, 'DisplayType', 'texturemap');
            geoshow(app.UIAxes,app.drone_positions(:, 1),  app.drone_positions(:, 2),  'DisplayType', 'point', 'Marker', '.', 'Color', 'r', 'MarkerSize', 30);
            for index = 1:app.TxNodeNum
                geoshow(app.UIAxes,app.path_enemy{index}(1, 1),   app.path_enemy{index}(1, 2), 'DisplayType', 'point', 'Marker', '.', 'MarkerEdgeColor', 'blue', 'MarkerSize', 10)
            end    
            app.input_bandwidth.Value =  num2str(app.Bandwidth);
            app.input_mod_type.Value = app.moduType;
            app.input_transmit_Power.Value = app.transmitPower;
            for i =1:app.TxNodeNum
                % re-color the linear gauge
           
%                 % for SingleNode ED
                threshold = app.DetcResult.Th.ED;
                app.(['SingleNodeEDTS_TX' num2str(i)]).Limits = [0.9, 1.1]*threshold;
                app.(['SingleNodeEDTS_TX' num2str(i)]).ScaleColors = [0 1 0; 1 0 0]; % RGB for green and red
                app.(['SingleNodeEDTS_TX' num2str(i)]).ScaleColorLimits = [0.9 1; 1 1.1]*threshold;
                % for Xcorr
                threshold = app.DetcResult.MulTh.Xcor ;
                app.(['XcorrTS_TX' num2str(i)]).Limits = [0.9, 1.1]*threshold;
                app.(['XcorrTS_TX' num2str(i)]).ScaleColors = [0 1 0; 1 0 0]; % RGB for green and red
                app.(['XcorrTS_TX' num2str(i)]).ScaleColorLimits = [0.9 1; 1 1.1]*threshold;
                % for GLRT
                threshold = app.DetcResult.MulTh.GLR;
                app.(['GLRTS_TX' num2str(i)]).Limits = [0, 2]*threshold;
                app.(['GLRTS_TX' num2str(i)]).ScaleColors = [0 1 0; 1 0 0]; % RGB for green and red
                app.(['GLRTS_TX' num2str(i)]).ScaleColorLimits = [0 1; 1 2]*threshold;


                app.source_para(i).modutype = app.input_mod_type.Value;
                app.source_para(i).transmit_Power = app.input_transmit_Power.Value;
                app.source_para(i).bandwidth = str2double(app.input_bandwidth.Value);
                app.source_para(i).CAR_F = app.CAR_F;
                app.source_para(i).path_enemy = app.path_enemy{i}; 
            end

            pushLogMsg(app, '初始化完成',0);


        end

        % Button pushed function: set_threshold_file_path
        function set_threshold_file_pathButtonPushed(app, event)
            % 使用uigetfile打开文件选择对话框
            [file,path] = uigetfile('*.mat', 'Select a MATLAB code file');

            % 如果用户没有取消，则更新文本框的内容
            if isequal(file,0)
                app.threshold_file_path.Value = 'User selected Cancel';
            else
                app.threshold_file_path.Value = fullfile(path, file); % 将完整的文件路径显示在文本框中
            end
            app.threshold_file_path.Tooltip = fullfile(path, file);
        end

        % Button pushed function: input_threshold_from_localfile
        function input_threshold_from_localfileButtonPushed(app, event)
            if (exist(app.threshold_file_path.Value, 'file') == 2)
                load(app.threshold_file_path.Value);
                pushLogMsg(app, '加载文件成功!',0);
            else
                pushLogMsg(app, ['无法加载指定的文件：', app.threshold_file_path.Value],0);
              
            end
        end

        % Button pushed function: auto_set_threshold
        function auto_set_thresholdButtonPushed(app, event)
            file_name_wharsh = fullfile('data/', ['DetcResult', num2str(round(str2double(app.input_sampRate.Value)/1e3)) 'K.mat']);
            if (exist(file_name_wharsh, 'file') == 2 && ~app.force_monte_carlo.Value)  % if thresholds have been stored
                load(file_name_wharsh);
                pushLogMsg(app, '当前环境下的门限值已有记录，系统已直接加载！',0);
            else
                DetcResult = findThresholdsByMonteCarlo(app);  % if thresholds have not been stored
                save(file_name_wharsh, 'DetcResult');
                pushLogMsg(app, '门限值已找到！',0);
                pushLogMsg(app, ['最新门限数据已保存在', file_name_wharsh, '文件中！'],0);
            end  
        end

        % Button pushed function: SetParaButton
        function SetParaButtonPushed(app, event)
            app.Flag = 1;
            value = app.TxDropDown.Value;
            app.TxNodeNumIndex = str2double(value(end));
            switch app.TxNodeNumIndex
                case 1
                      app.source_para(1).modutype = app.input_mod_type.Value;
                      app.source_para(1).transmit_Power = app.input_transmit_Power.Value;
                      app.source_para(1).bandwidth = str2double(app.input_bandwidth.Value);
                case 2
                      app.source_para(2).modutype = app.input_mod_type.Value;
                      app.source_para(2).transmit_Power = app.input_transmit_Power.Value;
                      app.source_para(2).bandwidth = str2double(app.input_bandwidth.Value); 
                case 3
                      app.source_para(3).modutype = app.input_mod_type.Value;
                      app.source_para(3).transmit_Power = app.input_transmit_Power.Value;
                      app.source_para(3).bandwidth = str2double(app.input_bandwidth.Value); 
                case 4
                      app.source_para(4).modutype = app.input_mod_type.Value;
                      app.source_para(4).transmit_Power = app.input_transmit_Power.Value;
                      app.source_para(4).bandwidth = str2double(app.input_bandwidth.Value); 
            end     

        end

        % Callback function: startButton
        function startButtonClicked(app, event)
              scDynamicTxV1(app)
          
%             delete(gcp('nocreate'))
%             pool = parpool('Threads');
% 
%                   switch app.TxNodeNumIndex
%            
%                      case 1
%                         f1 = parfeval(pool,@scDynamicTx_1, 1,app,1);
%                         afterEach(f1,@disp,0);                        
%                      case 2 
% 
%                         scDynamicTx_1(app,2);
%                      case 3
%                         scDynamicTx_1(app,3);
%                      case 4
%                         scDynamicTx_1(app,4);
%            
%                   end
% 
%             if app.TxNodeNumIndex <= 4
%                 while true
%                 scDynamicTx_1(app,app.TxNodeNumIndex);
%                 end
%             end
% 
%             if  app.TxNodeNumIndex  <=4
%                 scDynamicTx_1(app,1);
%             end
%             if app.TxNodeNumIndex <=4 &&  app.TxNodeNumIndex>=2
%                 scDynamicTx_2(app,2);
%             end
% 
%             if app.TxNodeNumIndex <=4 &&  app.TxNodeNumIndex>=3
%                 scDynamicTx_3(app,3);
%             end


%                   switch app.TxNodeNumIndex
%            
%                      case 1
%                         scDynamicTx_1(app,1);                         
%                      case 2 
%                         scDynamicTx_1(app,2);
%                      case 3
%                         scDynamicTx_1(app,3);
%                      case 4
%                         scDynamicTx_1(app,4);
%            
%                   end


        end

        % Callback function: stopButton
        function stopButtonClicked(app, event)
            delete(app);
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.Flag = 0;
            cla(app.UIAxes);
            startupFcn(app);
        end

        % Callback function: PushPauseButton
        function PushPauseButtonClicked(app, event)
            uiwait(app.UIFigure);
        end

        % Callback function: PushRestartButoon
        function PushRestartButoonClicked(app, event)
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.startButton = uipushtool(app.Toolbar);
            app.startButton.ClickedCallback = createCallbackFcn(app, @startButtonClicked, true);
            app.startButton.Icon = fullfile(pathToMLAPP, 'icons', 'start-50.png');
            app.PushPauseButton = uipushtool(app.Toolbar);
            app.PushPauseButton.ClickedCallback = createCallbackFcn(app, @PushPauseButtonClicked, true);
            app.PushPauseButton.Icon = 'pause-50.png';
            app.PushRestartButoon = uipushtool(app.Toolbar);
            app.PushRestartButoon.ClickedCallback = createCallbackFcn(app, @PushRestartButoonClicked, true);
            app.PushRestartButoon.Icon = 'continue.png';
            app.stopButton = uipushtool(app.Toolbar);
            app.stopButton.ClickedCallback = createCallbackFcn(app, @stopButtonClicked, true);
            app.stopButton.Icon = fullfile(pathToMLAPP, 'icons', 'stop.png');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '地图')
            xlabel(app.UIAxes, '经度')
            ylabel(app.UIAxes, '纬度')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TX1detcAndLoca = uitab(app.TabGroup);
            app.TX1detcAndLoca.Title = 'TX1检测结果';
            app.Detection_TX1 = uipanel(app.TX1detcAndLoca);
            app.Detection_TX1.Title = '检测';
            app.SingleNodeEDTS_TX1 = uigauge(app.Detection_TX1, 'linear');
            app.Method = uilabel(app.Detection_TX1);
            app.Method.Text = '方法名称';
            app.Label_19 = uilabel(app.Detection_TX1);
            app.Label_19.Text = '检验统计量';
            app.SingleNodeED = uilabel(app.Detection_TX1);
            app.SingleNodeED.Text = '单节点ED';
            app.Xcorr = uilabel(app.Detection_TX1);
            app.Xcorr.Text = '互相关';
            app.XcorrTS_TX1 = uigauge(app.Detection_TX1, 'linear');
            app.GLR = uilabel(app.Detection_TX1);
            app.GLR.Text = 'GLR';
            app.GLRTS_TX1 = uigauge(app.Detection_TX1, 'linear');
            app.Loca_TX1 = uipanel(app.TX1detcAndLoca);
            app.Loca_TX1.Title = '定位';
            app.UIAxes_Posi_TX1 = uiaxes(app.Loca_TX1);
            title(app.UIAxes_Posi_TX1, '定位误差')
            xlabel(app.UIAxes_Posi_TX1, 'countT(1s)')
            ylabel(app.UIAxes_Posi_TX1, 'RMSE/m')
            zlabel(app.UIAxes_Posi_TX1, 'Z')
            app.TX2detcAndLoca = uitab(app.TabGroup);
            app.TX2detcAndLoca.Title = 'TX2检测结果';
            app.Detection_TX2 = uipanel(app.TX2detcAndLoca);
            app.Detection_TX2.Title = '检测';
            app.SingleNodeEDTS_TX2 = uigauge(app.Detection_TX2, 'linear');
            app.Method_2 = uilabel(app.Detection_TX2);
            app.Method_2.Text = '方法名称';
            app.Label_20 = uilabel(app.Detection_TX2);
            app.Label_20.Text = '检验统计量';
            app.SingleNodeED_2 = uilabel(app.Detection_TX2);
            app.SingleNodeED_2.Text = '单节点ED';
            app.Xcorr_2 = uilabel(app.Detection_TX2);
            app.Xcorr_2.Text = '互相关';
            app.XcorrTS_TX2 = uigauge(app.Detection_TX2, 'linear');
            app.GLR_2 = uilabel(app.Detection_TX2);
            app.GLR_2.Text = 'GLR';
            app.GLRTS_TX2 = uigauge(app.Detection_TX2, 'linear');
            app.Loca_TX2 = uipanel(app.TX2detcAndLoca);
            app.Loca_TX2.Title = '定位';
            app.UIAxes_Posi_TX2 = uiaxes(app.Loca_TX2);
            title(app.UIAxes_Posi_TX2, '定位误差')
            xlabel(app.UIAxes_Posi_TX2, 'countT(1s)')
            ylabel(app.UIAxes_Posi_TX2, 'RMSE/m')
            zlabel(app.UIAxes_Posi_TX2, 'Z')
            app.TX3detcAndLoca = uitab(app.TabGroup);
            app.TX3detcAndLoca.Title = 'TX3检测结果';
            app.Detection_TX3 = uipanel(app.TX3detcAndLoca);
            app.Detection_TX3.Title = '检测';
            app.SingleNodeEDTS_TX3 = uigauge(app.Detection_TX3, 'linear');
            app.Method_3 = uilabel(app.Detection_TX3);
            app.Method_3.Text = '方法名称';
            app.Label_21 = uilabel(app.Detection_TX3);
            app.Label_21.Text = '检验统计量';
            app.SingleNodeED_3 = uilabel(app.Detection_TX3);
            app.SingleNodeED_3.Text = '单节点ED';
            app.Xcorr_3 = uilabel(app.Detection_TX3);
            app.Xcorr_3.Text = '互相关';
            app.XcorrTS_TX3 = uigauge(app.Detection_TX3, 'linear');
            app.GLR_3 = uilabel(app.Detection_TX3);
            app.GLR_3.Text = 'GLR';
            app.GLRTS_TX3 = uigauge(app.Detection_TX3, 'linear');
            app.Loca_TX3 = uipanel(app.TX3detcAndLoca);
            app.Loca_TX3.Title = '定位';
            app.UIAxes_Posi_TX3 = uiaxes(app.Loca_TX3);
            title(app.UIAxes_Posi_TX3, '定位误差')
            xlabel(app.UIAxes_Posi_TX3, 'countT(1s)')
            ylabel(app.UIAxes_Posi_TX3, 'RMSE/m')
            zlabel(app.UIAxes_Posi_TX3, 'Z')
            app.TX4TabdetcAndLoca = uitab(app.TabGroup);
            app.TX4TabdetcAndLoca.Title = 'TX4检测结果';
            app.Detection_TX4 = uipanel(app.TX4TabdetcAndLoca);
            app.Detection_TX4.Title = '检测';
            app.SingleNodeEDTS_TX4 = uigauge(app.Detection_TX4, 'linear');
            app.Method_4 = uilabel(app.Detection_TX4);
            app.Method_4.Text = '方法名称';
            app.Label_22 = uilabel(app.Detection_TX4);
            app.Label_22.Text = '检验统计量';
            app.SingleNodeED_4 = uilabel(app.Detection_TX4);
            app.SingleNodeED_4.Text = '单节点ED';
            app.Xcorr_4 = uilabel(app.Detection_TX4);
            app.Xcorr_4.Text = '互相关';
            app.XcorrTS_TX4 = uigauge(app.Detection_TX4, 'linear');
            app.GLR_4 = uilabel(app.Detection_TX4);
            app.GLR_4.Text = 'GLR';
            app.GLRTS_TX4 = uigauge(app.Detection_TX4, 'linear');
            app.Loca_TX4 = uipanel(app.TX4TabdetcAndLoca);
            app.Loca_TX4.Title = '定位';
            app.UIAxes_Posi_TX4 = uiaxes(app.Loca_TX4);
            title(app.UIAxes_Posi_TX4, '定位误差')
            xlabel(app.UIAxes_Posi_TX4, 'countT(1s)')
            ylabel(app.UIAxes_Posi_TX4, 'RMSE/m')
            zlabel(app.UIAxes_Posi_TX4, 'Z')
            app.TxPanel = uipanel(app.UIFigure);
            app.TxPanel.Title = 'Tx系统参数';
            app.TxDropDownLabel = uilabel(app.TxPanel);
            app.TxDropDownLabel.Text = 'Tx编号';
            app.TxDropDown = uidropdown(app.TxPanel);
            app.TxDropDown.Items = {'Source 1', 'Source 2', 'Source 3', 'Source 4'};
            app.TxDropDown.Editable = 'on';
            app.TxDropDown.Value = 'Source 1';
            app.TxPanel_Basic_System_Parameters = uipanel(app.TxPanel);
            app.TxPanel_Basic_System_Parameters.Title = '基本系统参数';
            app.Label_4 = uilabel(app.TxPanel_Basic_System_Parameters);
            app.Label_4.Text = '调制方式';
            app.input_mod_type = uidropdown(app.TxPanel_Basic_System_Parameters);
            app.input_mod_type.Items = {'QPSK', 'BPSK', '16QAM'};
            app.input_mod_type.Editable = 'on';
            app.input_mod_type.Value = 'QPSK';
            app.Label_5 = uilabel(app.TxPanel_Basic_System_Parameters);
            app.Label_5.Text = '发射功率   (W)';
            app.input_transmit_Power = uieditfield(app.TxPanel_Basic_System_Parameters, 'numeric');
            app.input_transmit_Power.Value = 0.1;
            app.Label_12 = uilabel(app.TxPanel_Basic_System_Parameters);
            app.Label_12.Text = '信号带宽（Hz）';
            app.input_bandwidth = uidropdown(app.TxPanel_Basic_System_Parameters);
            app.input_bandwidth.Items = {'10000', '20000', '50000', '200000'};
            app.input_bandwidth.Editable = 'on';
            app.input_bandwidth.Value = '10000';
            app.SetParaButton = uibutton(app.TxPanel_Basic_System_Parameters, 'push');
            app.SetParaButton.ButtonPushedFcn = createCallbackFcn(app, @SetParaButtonPushed, true);
            app.SetParaButton.Text = '参数设置';
            app.Reset = uibutton(app.TxPanel_Basic_System_Parameters, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = '重置';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.RxTab = uitab(app.TabGroup2);
            app.RxTab.Title = 'Rx系统参数';
            app.RxPanel_Basic_System_Parameters_2 = uipanel(app.RxTab);
            app.RxPanel_Basic_System_Parameters_2.Title = '基本系统参数';
            app.Label_13 = uilabel(app.RxPanel_Basic_System_Parameters_2);
            app.Label_13.Text = '观测时间（s）';
            app.input_obsTime = uidropdown(app.RxPanel_Basic_System_Parameters_2);
            app.input_obsTime.Items = {'0.1', '0.5'};
            app.input_obsTime.Value = '0.1';
            app.Label_15 = uilabel(app.RxPanel_Basic_System_Parameters_2);
            app.Label_15.Text = '采样率（Hz）';
            app.input_sampRate = uidropdown(app.RxPanel_Basic_System_Parameters_2);
            app.input_sampRate.Items = {'200000', '600000'};
            app.input_sampRate.Value = '200000';
            app.RxDropDownLabel = uilabel(app.RxPanel_Basic_System_Parameters_2);
            app.RxDropDownLabel.Text = 'Rx编号';
            app.RxDropDown = uidropdown(app.RxPanel_Basic_System_Parameters_2);
            app.RxDropDown.Items = {'RecNode 1', 'RecNode 2', 'RecNode 3', 'RecNode 4', 'RecNode 5', 'RecNode 6'};
            app.RxDropDown.Value = 'RecNode 1';
            app.Panel = uipanel(app.RxTab);
            app.Panel.Title = '定位方法选项卡';
            app.TDOA_Button = uicheckbox(app.Panel);
            app.TDOA_Button.Text = 'TDOA';
            app.GLR_DPD_Button = uicheckbox(app.Panel);
            app.GLR_DPD_Button.Text = '直接定位';
            app.GLR_DPD_Interp_Button = uicheckbox(app.Panel);
            app.GLR_DPD_Interp_Button.Text = '直接定位+插值';
            app.GLR_DPD_PHD_Button = uicheckbox(app.Panel);
            app.GLR_DPD_PHD_Button.Text = '直接定位+PHD';
            app.Tab_9 = uitab(app.TabGroup2);
            app.Tab_9.Title = '检测门限';
            app.set_threshold_file_path = uibutton(app.Tab_9, 'push');
            app.set_threshold_file_path.ButtonPushedFcn = createCallbackFcn(app, @set_threshold_file_pathButtonPushed, true);
            app.set_threshold_file_path.Text = '选择文件 (.mat)';
            app.auto_set_threshold = uibutton(app.Tab_9, 'push');
            app.auto_set_threshold.ButtonPushedFcn = createCallbackFcn(app, @auto_set_thresholdButtonPushed, true);
            app.auto_set_threshold.Text = '自动生成';
            app.input_threshold_from_localfile = uibutton(app.Tab_9, 'push');
            app.input_threshold_from_localfile.ButtonPushedFcn = createCallbackFcn(app, @input_threshold_from_localfileButtonPushed, true);
            app.input_threshold_from_localfile.Text = '【确认】导入';
            app.force_monte_carlo = uicheckbox(app.Tab_9);
            app.force_monte_carlo.Text = '强制重新生成门限';
            app.Label_16 = uilabel(app.Tab_9);
            app.Label_16.Text = '从文件导入：';
            app.threshold_file_path = uieditfield(app.Tab_9, 'text');
            app.Label_17 = uilabel(app.Tab_9);
            app.Label_17.Text = '仿真次数：';
            app.input_MC = uieditfield(app.Tab_9, 'numeric');
            app.input_MC.Limits = [100 Inf];
            app.input_MC.Value = 1000;
            app.Label_18 = uilabel(app.Tab_9);
            app.Label_18.Text = '虚警率：';
            app.input_pf = uieditfield(app.Tab_9, 'numeric');
            app.input_pf.Limits = [1e-08 0.2];
            app.input_pf.Value = 0.01;
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.Tab_10 = uitab(app.TabGroup3);
            app.Tab_10.Title = '初始化';
            app.TextArea_TX0 = uitextarea(app.Tab_10);
            app.TX1Log = uitab(app.TabGroup3);
            app.TX1Log.Title = 'TX1仿真日志';
            app.TextArea_TX1 = uitextarea(app.TX1Log);
            app.TX2Log = uitab(app.TabGroup3);
            app.TX2Log.Title = 'TX2仿真日志';
            app.TextArea_TX2 = uitextarea(app.TX2Log);
            app.TX3Log = uitab(app.TabGroup3);
            app.TX3Log.Title = 'TX3仿真日志';
            app.TextArea_TX3 = uitextarea(app.TX3Log);
            app.TX4Log = uitab(app.TabGroup3);
            app.TX4Log.Title = 'TX4仿真日志';
            app.TextArea_TX4 = uitextarea(app.TX4Log);
            app.TDOA_Button1 = uibutton(app.UIFigure, 'state');
            app.TDOA_Button1.Text = 'TDOA';
            app.GLR_DPD_Button1 = uibutton(app.UIFigure, 'state');
            app.GLR_DPD_Button1.Text = '直接定位';
            app.GLR_DPD_Interp_Button1 = uibutton(app.UIFigure, 'state');
            app.GLR_DPD_Interp_Button1.Text = '直接定位+插值';
            app.GLR_DPD_PHD_Button1 = uibutton(app.UIFigure, 'state');
            app.GLR_DPD_PHD_Button1.Text = '直接定位+PHD';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mytestV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end